classdef Spectrum_Analyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        BufferSizeLabel             matlab.ui.control.Label
        CallibratingLabel           matlab.ui.control.Label
        CustomTrimEditField         matlab.ui.control.NumericEditField
        CustomTrimEditFieldLabel    matlab.ui.control.Label
        RecordLengthEditFieldLabel  matlab.ui.control.Label
        RecordLengthEditField       matlab.ui.control.NumericEditField
        PlotButton                  matlab.ui.control.Button
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        Fs = 44100; %Sampling Frequency
        nBits = 16; %Bitrate
        nChannels = 1; %Number of Channels (mono/stereo)
        ID = -1;       % default audio input device 
        recObj = audiorecorder(Fs,nBits,nChannels,ID);%Create the recording handle
        recordblocking(recObj,1);%Record!

        ytp = getaudiodata(recObj);%Get Audio Data from the Record Handle
        calind=find(abs(ytp)>0.001,1);
        app.CallibratingLabel.Text=num2str(calind/Fs);
        app.CustomTrimEditField.Value=calind/Fs;
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
        Fs = 44100;
        nBits = 16; 
        nChannels = 1; 
        ID = -1;       % default audio input device 
        recObj = audiorecorder(Fs,nBits,nChannels,ID);
        recDuration=app.RecordLengthEditField.Value;
        recordblocking(recObj,recDuration+app.CustomTrimEditField.Value);
    
        ytp = getaudiodata(recObj);
        y=ytp((1+app.CustomTrimEditField.Value*Fs):end);   %Buffer Time depends on machine/OS
        t=(1:length(y))/Fs;
    
        L=length(y);
        f = Fs*(0:(L-1)/2)/L;
        Y=fft(y);
        P2 = abs(Y/L);
        P1 = P2(1:(L+1)/2);
        P1(2:end) = 2*P1(2:end);

        plot(app.UIAxes2,t,y)
        xlim(app.UIAxes2,[0 max(t)])

        plot(app.UIAxes,f(2:end),log10(P1(2:end))-max(log10(P1(2:end)))) 

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'FFT')
            xlabel(app.UIAxes, 'Frequency(Hz)')
            ylabel(app.UIAxes, 'Normalized Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 16000];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Waveform')
            xlabel(app.UIAxes2, 'Time(s)')
            ylabel(app.UIAxes2, 'Amplitude')
            zlabel(app.UIAxes2, 'Z')
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.RecordLengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.RecordLengthEditField.Value = 1;
            app.RecordLengthEditFieldLabel = uilabel(app.UIFigure);
            app.RecordLengthEditFieldLabel.Text = 'Record Length';
            app.CustomTrimEditFieldLabel = uilabel(app.UIFigure);
            app.CustomTrimEditFieldLabel.Text = 'Custom Trim';
            app.CustomTrimEditField = uieditfield(app.UIFigure, 'numeric');
            app.CustomTrimEditField.Value = 0.4;
            app.CallibratingLabel = uilabel(app.UIFigure);
            app.CallibratingLabel.Text = 'Callibrating...';
            app.BufferSizeLabel = uilabel(app.UIFigure);
            app.BufferSizeLabel.Text = 'Buffer Size:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Spectrum_Analyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end