classdef generatorApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        FrequencyEditField       matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel  matlab.ui.control.Label
        DoneButton               matlab.ui.control.Button
        MaxFreqEditField         matlab.ui.control.NumericEditField
        MaxFreqEditFieldLabel    matlab.ui.control.Label
        SawtoothSliderLabel      matlab.ui.control.Label
        TriangleSliderLabel      matlab.ui.control.Label
        SquareSliderLabel        matlab.ui.control.Label
        SineSliderLabel          matlab.ui.control.Label
        SawtoothSlider           matlab.ui.control.Slider
        TriangleSlider           matlab.ui.control.Slider
        SquareSlider             matlab.ui.control.Slider
        SineSlider               matlab.ui.control.Slider
        UIAxes_2                 matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp
    end
    
    properties (Access = public)
        func2plot % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            app.CallingApp = caller;
            Fs=44100;
            duration=1;
            t=0:1/Fs:duration;            
            freq=app.FrequencyEditField.Value;
            T=1/freq; 
            app.func2plot.t=t;
            app.func2plot.ysin=sin(freq*2*pi*t);
            app.func2plot.ysq=square(freq*2*pi*t);
            app.func2plot.ytri=2*(abs(sawtooth(freq*2*pi*(t-T/4)))-1/2);
            app.func2plot.ysaw=sawtooth(freq*2*pi*(t+T/2));

            app.func2plot.sin1=0;
            app.func2plot.sq1=0;
            app.func2plot.tri1=0;
            app.func2plot.saw1=0;

            app.func2plot.sinco=0;
            app.func2plot.sqco=0;
            app.func2plot.trico=0;
            app.func2plot.sawco=0;
            %app.func2plot.y=0;
            %hold(app.UIAxes,'on')
            title(app.UIAxes,'Waveform')
            xlabel(app.UIAxes,'Time(s)')
            ylabel(app.UIAxes,'Amplitude')
        end

        % Value changed function: SineSlider
        function SineSliderValueChanged(app, event)
            value = app.SineSlider.Value;
            app.func2plot.sinco=value;
            y=app.func2plot.ysin*app.func2plot.sinco+app.func2plot.ysq*app.func2plot.sqco...
                +app.func2plot.ytri*app.func2plot.trico+app.func2plot.ysaw*app.func2plot.sawco;
            plot(app.UIAxes,app.func2plot.t,y);
            xlim(app.UIAxes,[0 1/app.FrequencyEditField.Value])
            Fs=44100;
            L=length(y);
            f = Fs*(0:(L-1)/2)/L;
            Y=fft(y);
            P2 = abs(Y/L);
            P1 = P2(1:(L+1)/2);
            P1(2:end) = 2*P1(2:end);
            plot(app.UIAxes_2,f(2:end),log10(P1(2:end))-max(log10(P1(2:end)))) 
            xlim(app.UIAxes_2,[0 app.MaxFreqEditField.Value])
            app.func2plot.y=y;

        end

        % Value changed function: SquareSlider
        function SquareSliderValueChanged(app, event)
            value = app.SquareSlider.Value;
            app.func2plot.sqco=value;
            y=app.func2plot.ysin*app.func2plot.sinco+app.func2plot.ysq*app.func2plot.sqco...
                +app.func2plot.ytri*app.func2plot.trico+app.func2plot.ysaw*app.func2plot.sawco;
            plot(app.UIAxes,app.func2plot.t,y);
            xlim(app.UIAxes,[0 1/app.FrequencyEditField.Value])
            Fs=44100;
            L=length(y);
            f = Fs*(0:(L-1)/2)/L;
            Y=fft(y);
            P2 = abs(Y/L);
            P1 = P2(1:(L+1)/2);
            P1(2:end) = 2*P1(2:end);
            plot(app.UIAxes_2,f(2:end),log10(P1(2:end))-max(log10(P1(2:end)))) 
            xlim(app.UIAxes_2,[0 app.MaxFreqEditField.Value])
            app.func2plot.y=y;

            
        end

        % Value changed function: TriangleSlider
        function TriangleSliderValueChanged(app, event)
            value = app.TriangleSlider.Value;
            app.func2plot.trico=value;
            y=app.func2plot.ysin*app.func2plot.sinco+app.func2plot.ysq*app.func2plot.sqco...
                +app.func2plot.ytri*app.func2plot.trico+app.func2plot.ysaw*app.func2plot.sawco;
            plot(app.UIAxes,app.func2plot.t,y);
            xlim(app.UIAxes,[0 1/app.FrequencyEditField.Value])
            Fs=44100;
            L=length(y);
            f = Fs*(0:(L-1)/2)/L;
            Y=fft(y);
            P2 = abs(Y/L);
            P1 = P2(1:(L+1)/2);
            P1(2:end) = 2*P1(2:end);
            plot(app.UIAxes_2,f(2:end),log10(P1(2:end))-max(log10(P1(2:end)))) 
            xlim(app.UIAxes_2,[0 app.MaxFreqEditField.Value])
            app.func2plot.y=y;
        end

        % Value changed function: SawtoothSlider
        function SawtoothSliderValueChanged(app, event)
            value = app.SawtoothSlider.Value;
            app.func2plot.sawco=value;
            y=app.func2plot.ysin*app.func2plot.sinco+app.func2plot.ysq*app.func2plot.sqco...
                +app.func2plot.ytri*app.func2plot.trico+app.func2plot.ysaw*app.func2plot.sawco;
            plot(app.UIAxes,app.func2plot.t,y);
            xlim(app.UIAxes,[0 1/app.FrequencyEditField.Value])
            Fs=44100;
            L=length(y);
            f = Fs*(0:(L-1)/2)/L;
            Y=fft(y);
            P2 = abs(Y/L);
            P1 = P2(1:(L+1)/2);
            P1(2:end) = 2*P1(2:end);
            plot(app.UIAxes_2,f(2:end),log10(P1(2:end))-max(log10(P1(2:end)))) 
            xlim(app.UIAxes_2,[0 app.MaxFreqEditField.Value])
            app.func2plot.y=y;
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            updatefunc(app.CallingApp,app.func2plot.y);
            delete(app)
        end

        % Value changed function: MaxFreqEditField
        function MaxFreqEditFieldValueChanged(app, event)
            value = app.MaxFreqEditField.Value;
            xlim(app.UIAxes_2,[0 value])
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.SineSlider = uislider(app.UIFigure);
            app.SineSlider.Limits = [0 1];
            app.SineSlider.ValueChangedFcn = createCallbackFcn(app, @SineSliderValueChanged, true);
            app.SquareSlider = uislider(app.UIFigure);
            app.SquareSlider.Limits = [0 1];
            app.SquareSlider.ValueChangedFcn = createCallbackFcn(app, @SquareSliderValueChanged, true);
            app.TriangleSlider = uislider(app.UIFigure);
            app.TriangleSlider.Limits = [0 1];
            app.TriangleSlider.ValueChangedFcn = createCallbackFcn(app, @TriangleSliderValueChanged, true);
            app.SawtoothSlider = uislider(app.UIFigure);
            app.SawtoothSlider.Limits = [0 1];
            app.SawtoothSlider.ValueChangedFcn = createCallbackFcn(app, @SawtoothSliderValueChanged, true);
            app.SineSliderLabel = uilabel(app.UIFigure);
            app.SineSliderLabel.Text = 'Sine';
            app.SquareSliderLabel = uilabel(app.UIFigure);
            app.SquareSliderLabel.Text = 'Square';
            app.TriangleSliderLabel = uilabel(app.UIFigure);
            app.TriangleSliderLabel.Text = 'Triangle';
            app.SawtoothSliderLabel = uilabel(app.UIFigure);
            app.SawtoothSliderLabel.Text = 'Sawtooth';
            app.MaxFreqEditFieldLabel = uilabel(app.UIFigure);
            app.MaxFreqEditFieldLabel.Text = 'Max Freq.';
            app.MaxFreqEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxFreqEditField.ValueChangedFcn = createCallbackFcn(app, @MaxFreqEditFieldValueChanged, true);
            app.MaxFreqEditField.Value = 50;
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.FrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyEditField.Value = 440;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = generatorApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end