classdef main_generator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        MaxFreqEditField           matlab.ui.control.NumericEditField
        MaxFreqEditFieldLabel      matlab.ui.control.Label
        MaxtimeEditField           matlab.ui.control.NumericEditField
        MaxtimeEditFieldLabel      matlab.ui.control.Label
        FilterTypeSwitch           matlab.ui.control.ToggleSwitch
        FilterTypeSwitchLabel      matlab.ui.control.Label
        FilterOrderEditField       matlab.ui.control.NumericEditField
        FilterOrderEditFieldLabel  matlab.ui.control.Label
        DurationEditField          matlab.ui.control.NumericEditField
        DurationEditFieldLabel     matlab.ui.control.Label
        FrequencyEditField         matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel    matlab.ui.control.Label
        PlayButton                 matlab.ui.control.Button
        EditField                  matlab.ui.control.NumericEditField
        NormalizedCutoffFrequencySlider  matlab.ui.control.Slider
        GeneratePlotButton         matlab.ui.control.Button
        WaveGeneratorButton        matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
        UIAxes_2                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        DialogApp
    end
    
    properties (Access = public)
        func2plotm % Description
        func2plot % Description
    end


    methods (Access = public)
        
        function updatefunc(app,y)
            app.func2plotm.y=y;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%         app.GeneratePlotButton.Enable = "off";
%         
%         Fs = 44100; %Sampling Frequency
%         nBits = 16; %Bitrate
%         nChannels = 1; %Number of Channels (mono/stereo)
%         ID = -1;       % default audio input device 
%         recObj = audiorecorder(Fs,nBits,nChannels,ID);%Create the recording handle
%         recordblocking(recObj,1);%Record!
% 
%         ytp = getaudiodata(recObj);%Get Audio Data from the Record Handle
%         y=ytp(1:end);   %Trimming, Buffer Time depends on machine/OS
%         calind=find(abs(y)>0.001,1);
%         app.CallibratingLabel.Text=num2str(calind/Fs);
% 
%         app.GeneratePlotButton.Enable = "on";
        app.func2plotm.y=0;

        title(app.UIAxes,'Waveform')
            xlabel(app.UIAxes,'Time(s)')
            ylabel(app.UIAxes,'Amplitude')
            title(app.UIAxes_2,"FFT")
            xlabel(app.UIAxes_2,"Frequency(Hz)")
            ylabel(app.UIAxes_2,'Normalized Amplitude')

        end

        % Button pushed function: GeneratePlotButton
        function GeneratePlotButtonPushed(app, event)


        yin=app.func2plotm.y; 

        y=[];
        Fs = 44100;
        for i=1:app.DurationEditField.Value
            y=[y yin];
            i=i+1;
        end
        t=(1:length(y))/Fs;
            
            ftype=app.FilterTypeSwitch.Value;
            [b,a]=butter(app.FilterOrderEditField.Value,app.EditField.Value,ftype);
            y=filter(b,a,y);

            app.func2plot.y=y;


            L=length(y);
            f = Fs*(0:(L-1)/2)/L;
            Y=fft(y);
            P2 = abs(Y/L);
            P1 = P2(1:(L+1)/2);
            P1(2:end) = 2*P1(2:end);


            plot(app.UIAxes,t,y)
            xlim(app.UIAxes,[0 app.MaxtimeEditField.Value])


            plot(app.UIAxes_2,f(2:end),log10(P1(2:end))-max(log10(P1(2:end)))) 
            xlim(app.UIAxes_2,[0 app.MaxFreqEditField.Value])


% 
%         plot(app.UIAxes,app.func2plotm.y)
% 
% 
% 
%         plot(app.UIAxes,t,y)
%         xlim(app.UIAxes,[0 max(t)])
%         plot(app.UIAxes_2,f(2:end),log10(P1(2:end))-max(log10(P1(2:end)))) 

        end

        % Button pushed function: WaveGeneratorButton
        function WaveGeneratorButtonPushed(app, event)
            %app.WaveGeneratorButton.Enable = "off";
            app.DialogApp = generatorApp(app);
        end

        % Value changing function: NormalizedCutoffFrequencySlider
        function NormalizedCutoffFrequencySliderValueChanging(app, event)
            changingValue = event.Value;
            app.EditField.Value=(changingValue);
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            if value<=1
            app.NormalizedCutoffFrequencySlider.Value=value;
            else
            app.NormalizedCutoffFrequencySlider.Value=0.999;
            app.EditField.Value=0.999;
            end
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
                        soundsc(app.func2plot.y,44100)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'FFT')
            xlabel(app.UIAxes_2, 'Frequency(Hz)')
            ylabel(app.UIAxes_2, 'Normalized Amplitude')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XLim = [0 16000];
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Waveform')
            xlabel(app.UIAxes, 'Time(s)')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.WaveGeneratorButton = uibutton(app.UIFigure, 'push');
            app.WaveGeneratorButton.ButtonPushedFcn = createCallbackFcn(app, @WaveGeneratorButtonPushed, true);
            app.WaveGeneratorButton.Text = 'Wave Generator';
            app.GeneratePlotButton = uibutton(app.UIFigure, 'push');
            app.GeneratePlotButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePlotButtonPushed, true);
            app.GeneratePlotButton.Text = 'Generate & Plot';
            app.NormalizedCutoffFrequencySlider = uislider(app.UIFigure);
            app.NormalizedCutoffFrequencySlider.Limits = [1e-08 0.999];
            app.NormalizedCutoffFrequencySlider.MajorTicks = [0.001 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 0.999];
            app.NormalizedCutoffFrequencySlider.ValueChangingFcn = createCallbackFcn(app, @NormalizedCutoffFrequencySliderValueChanging, true);
            app.NormalizedCutoffFrequencySlider.Value = 0.5;
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 0.5;
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.FrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyEditField.Value = 440;
            app.DurationEditFieldLabel = uilabel(app.UIFigure);
            app.DurationEditFieldLabel.Text = 'Duration';
            app.DurationEditField = uieditfield(app.UIFigure, 'numeric');
            app.DurationEditField.Value = 1;
            app.FilterOrderEditFieldLabel = uilabel(app.UIFigure);
            app.FilterOrderEditFieldLabel.Text = 'Filter Order';
            app.FilterOrderEditField = uieditfield(app.UIFigure, 'numeric');
            app.FilterOrderEditField.Value = 5;
            app.FilterTypeSwitchLabel = uilabel(app.UIFigure);
            app.FilterTypeSwitchLabel.Text = 'Filter Type';
            app.FilterTypeSwitch = uiswitch(app.UIFigure, 'toggle');
            app.FilterTypeSwitch.Items = {'Low', 'High'};
            app.FilterTypeSwitch.Value = 'Low';
            app.MaxtimeEditFieldLabel = uilabel(app.UIFigure);
            app.MaxtimeEditFieldLabel.Text = 'Max time';
            app.MaxtimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxtimeEditField.Value = 1;
            app.MaxFreqEditFieldLabel = uilabel(app.UIFigure);
            app.MaxFreqEditFieldLabel.Text = 'Max Freq.';
            app.MaxFreqEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxFreqEditField.Value = 16000;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main_generator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end